classdef AP_launcher_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        overwriteCheckBox             matlab.ui.control.CheckBox
        pALabel                       matlab.ui.control.Label
        intervalEditField             matlab.ui.control.NumericEditField
        intervalEditFieldLabel        matlab.ui.control.Label
        nsweepsEditField              matlab.ui.control.NumericEditField
        nsweepsEditFieldLabel         matlab.ui.control.Label
        Lamp                          matlab.ui.control.Lamp
        Image                         matlab.ui.control.Image
        DETAILSOFACQUISITIONLabel     matlab.ui.control.Label
        msLabel_2                     matlab.ui.control.Label
        DurationstepEditField         matlab.ui.control.NumericEditField
        DurationstepEditFieldLabel    matlab.ui.control.Label
        msLabel                       matlab.ui.control.Label
        StartstepEditField            matlab.ui.control.NumericEditField
        StartstepEditFieldLabel       matlab.ui.control.Label
        sLabel                        matlab.ui.control.Label
        DurationEditField             matlab.ui.control.NumericEditField
        DurationEditFieldLabel        matlab.ui.control.Label
        HzLabel                       matlab.ui.control.Label
        SamplingrateEditField         matlab.ui.control.NumericEditField
        SamplingrateEditFieldLabel    matlab.ui.control.Label
        CheckcellsCheckBox            matlab.ui.control.CheckBox
        DrawAPsLabel                  matlab.ui.control.Label
        ACTIONPOTENTIALANALYSISLabel  matlab.ui.control.Label
        RunAPanalysisButton           matlab.ui.control.Button
        DateTextArea                  matlab.ui.control.TextArea
        DateTextAreaLabel             matlab.ui.control.Label
        ExperimenterDropDown          matlab.ui.control.DropDown
        ExperimenterDropDownLabel     matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunAPanalysisButton
        function RunAPanalysisButtonPushed(app, event)

            app.Lamp.Color = 'red';
            pause(1)
            done = 0;
            if ~done
                done = run_AP_analysis(app.ExperimenterDropDown.Value, app.DateTextArea.Value,...
                    app.CheckcellsCheckBox.Value,...
                    app.SamplingrateEditField.Value, app.DurationEditField.Value, ...
                    app.StartstepEditField.Value, app.DurationstepEditField.Value, app.nsweepsEditField.Value,...
                    app.intervalEditField.Value, app.overwriteCheckBox.Value);
                if done
                    app.Lamp.Color = 'green';
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Samples')
            ylabel(app.UIAxes, 'Membrane potential (mV)')
            zlabel(app.UIAxes, 'Z')
            app.ExperimenterDropDownLabel = uilabel(app.UIFigure);
            app.ExperimenterDropDownLabel.Text = 'Experimenter';
            app.ExperimenterDropDown = uidropdown(app.UIFigure);
            app.ExperimenterDropDown.Items = {'Federica', 'Fede_setup_1_5HT7', 'Fede_setup_1', 'Liselot', 'Liselot_setup_1', 'Niels', 'Kristina', 'Sri', 'Falk_et_at', 'Franziska'};
            app.ExperimenterDropDown.Value = 'Federica';
            app.DateTextAreaLabel = uilabel(app.UIFigure);
            app.DateTextAreaLabel.Text = 'Date';
            app.DateTextArea = uitextarea(app.UIFigure);
            app.DateTextArea.Value = {'050618'};
            app.RunAPanalysisButton = uibutton(app.UIFigure, 'push');
            app.RunAPanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAPanalysisButtonPushed, true);
            app.RunAPanalysisButton.Text = 'Run AP analysis';
            app.ACTIONPOTENTIALANALYSISLabel = uilabel(app.UIFigure);
            app.ACTIONPOTENTIALANALYSISLabel.Text = 'ACTION POTENTIAL ANALYSIS';
            app.DrawAPsLabel = uilabel(app.UIFigure);
            app.DrawAPsLabel.Text = 'Draw APs';
            app.CheckcellsCheckBox = uicheckbox(app.UIFigure);
            app.CheckcellsCheckBox.Text = 'Check cells';
            app.CheckcellsCheckBox.Value = true;
            app.SamplingrateEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingrateEditFieldLabel.Text = 'Sampling rate';
            app.SamplingrateEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingrateEditField.Value = 8000;
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.DurationEditFieldLabel = uilabel(app.UIFigure);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationEditField.Value = 1;
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = 's';
            app.StartstepEditFieldLabel = uilabel(app.UIFigure);
            app.StartstepEditFieldLabel.Text = 'Start step';
            app.StartstepEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartstepEditField.Value = 100;
            app.msLabel = uilabel(app.UIFigure);
            app.msLabel.Text = 'ms';
            app.DurationstepEditFieldLabel = uilabel(app.UIFigure);
            app.DurationstepEditFieldLabel.Text = 'Duration step';
            app.DurationstepEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationstepEditField.Value = 600;
            app.msLabel_2 = uilabel(app.UIFigure);
            app.msLabel_2.Text = 'ms';
            app.DETAILSOFACQUISITIONLabel = uilabel(app.UIFigure);
            app.DETAILSOFACQUISITIONLabel.Text = 'DETAILS OF ACQUISITION';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'sample2.png';
            app.Lamp = uilamp(app.UIFigure);
            app.nsweepsEditFieldLabel = uilabel(app.UIFigure);
            app.nsweepsEditFieldLabel.Text = 'n sweeps';
            app.nsweepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.nsweepsEditField.Value = 10;
            app.intervalEditFieldLabel = uilabel(app.UIFigure);
            app.intervalEditFieldLabel.Text = 'interval';
            app.intervalEditField = uieditfield(app.UIFigure, 'numeric');
            app.intervalEditField.Value = 100;
            app.pALabel = uilabel(app.UIFigure);
            app.pALabel.Text = 'pA';
            app.overwriteCheckBox = uicheckbox(app.UIFigure);
            app.overwriteCheckBox.Text = 'overwrite';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AP_launcher_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end