classdef EPSP_launcher_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        msLabel_5                     matlab.ui.control.Label
        WindowtopeakEditField         matlab.ui.control.NumericEditField
        WindowtopeakEditFieldLabel    matlab.ui.control.Label
        sLabel                        matlab.ui.control.Label
        TotaldurationEditField        matlab.ui.control.NumericEditField
        TotaldurationEditFieldLabel   matlab.ui.control.Label
        msLabel_4                     matlab.ui.control.Label
        TestpulsedurationEditField    matlab.ui.control.NumericEditField
        TestpulsedurationEditFieldLabel  matlab.ui.control.Label
        overwriteCheckBox             matlab.ui.control.CheckBox
        Lamp                          matlab.ui.control.Lamp
        DETAILSOFACQUISITIONLabel     matlab.ui.control.Label
        pALabel                       matlab.ui.control.Label
        TestpulseamplEditField        matlab.ui.control.NumericEditField
        TestpulseamplEditFieldLabel   matlab.ui.control.Label
        msLabel                       matlab.ui.control.Label
        StarttestpulseEditField       matlab.ui.control.NumericEditField
        StarttestpulseEditFieldLabel  matlab.ui.control.Label
        msLabel_3                     matlab.ui.control.Label
        StartstimulusEditField        matlab.ui.control.NumericEditField
        StartstimulusEditFieldLabel   matlab.ui.control.Label
        HzLabel                       matlab.ui.control.Label
        SamplingrateEditField         matlab.ui.control.NumericEditField
        SamplingrateEditFieldLabel    matlab.ui.control.Label
        CheckcellsCheckBox            matlab.ui.control.CheckBox
        DrawAPsLabel                  matlab.ui.control.Label
        EPSPANALYSISLabel             matlab.ui.control.Label
        RunEPSPanalysisButton         matlab.ui.control.Button
        DateTextArea                  matlab.ui.control.TextArea
        DateTextAreaLabel             matlab.ui.control.Label
        ExperimenterDropDown          matlab.ui.control.DropDown
        ExperimenterDropDownLabel     matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunEPSPanalysisButton
        function RunEPSPanalysisButtonPushed(app, event)

            app.Lamp.Color = 'red';
            pause(1)
            done = 0;
            if ~done
                done = run_EPSP_analysis(app.ExperimenterDropDown.Value, app.DateTextArea.Value,...
                    app.CheckcellsCheckBox.Value,...
                    app.SamplingrateEditField.Value, app.StartstimulusEditField.Value, ...
                    app.StarttestpulseEditField.Value, app.TestpulseamplEditField.Value,...
                    app.TestpulsedurationEditField.Value, app.TotaldurationEditField.Value,...
                    app.WindowtopeakEditField.Value, ...
                    app.overwriteCheckBox.Value);
                if done
                    app.Lamp.Color = 'green';
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'time (ms)')
            ylabel(app.UIAxes, 'Membrane potential (mV)')
            zlabel(app.UIAxes, 'Z')
            app.ExperimenterDropDownLabel = uilabel(app.UIFigure);
            app.ExperimenterDropDownLabel.Text = 'Experimenter';
            app.ExperimenterDropDown = uidropdown(app.UIFigure);
            app.ExperimenterDropDown.Items = {'Federica', 'Liselot', 'Niels', 'Kristina'};
            app.ExperimenterDropDown.Value = 'Federica';
            app.DateTextAreaLabel = uilabel(app.UIFigure);
            app.DateTextAreaLabel.Text = 'Date';
            app.DateTextArea = uitextarea(app.UIFigure);
            app.DateTextArea.Value = {'050618'};
            app.RunEPSPanalysisButton = uibutton(app.UIFigure, 'push');
            app.RunEPSPanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunEPSPanalysisButtonPushed, true);
            app.RunEPSPanalysisButton.Text = 'Run EPSP analysis';
            app.EPSPANALYSISLabel = uilabel(app.UIFigure);
            app.EPSPANALYSISLabel.Text = 'EPSP ANALYSIS';
            app.DrawAPsLabel = uilabel(app.UIFigure);
            app.DrawAPsLabel.Text = 'Draw APs';
            app.CheckcellsCheckBox = uicheckbox(app.UIFigure);
            app.CheckcellsCheckBox.Text = 'Check cells';
            app.CheckcellsCheckBox.Value = true;
            app.SamplingrateEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingrateEditFieldLabel.Text = 'Sampling rate';
            app.SamplingrateEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingrateEditField.Value = 8000;
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.StartstimulusEditFieldLabel = uilabel(app.UIFigure);
            app.StartstimulusEditFieldLabel.Text = 'Start stimulus';
            app.StartstimulusEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartstimulusEditField.Value = 100;
            app.msLabel_3 = uilabel(app.UIFigure);
            app.msLabel_3.Text = 'ms';
            app.StarttestpulseEditFieldLabel = uilabel(app.UIFigure);
            app.StarttestpulseEditFieldLabel.Text = 'Start test pulse';
            app.StarttestpulseEditField = uieditfield(app.UIFigure, 'numeric');
            app.StarttestpulseEditField.Value = 600;
            app.msLabel = uilabel(app.UIFigure);
            app.msLabel.Text = 'ms';
            app.TestpulseamplEditFieldLabel = uilabel(app.UIFigure);
            app.TestpulseamplEditFieldLabel.Text = 'Test pulse ampl';
            app.TestpulseamplEditField = uieditfield(app.UIFigure, 'numeric');
            app.TestpulseamplEditField.Value = -50;
            app.pALabel = uilabel(app.UIFigure);
            app.pALabel.Text = 'pA';
            app.DETAILSOFACQUISITIONLabel = uilabel(app.UIFigure);
            app.DETAILSOFACQUISITIONLabel.Text = 'DETAILS OF ACQUISITION';
            app.Lamp = uilamp(app.UIFigure);
            app.overwriteCheckBox = uicheckbox(app.UIFigure);
            app.overwriteCheckBox.Text = 'overwrite';
            app.TestpulsedurationEditFieldLabel = uilabel(app.UIFigure);
            app.TestpulsedurationEditFieldLabel.Text = 'Test pulse duration';
            app.TestpulsedurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.TestpulsedurationEditField.Value = 300;
            app.msLabel_4 = uilabel(app.UIFigure);
            app.msLabel_4.Text = 'ms';
            app.TotaldurationEditFieldLabel = uilabel(app.UIFigure);
            app.TotaldurationEditFieldLabel.Text = 'Total duration';
            app.TotaldurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotaldurationEditField.Value = 1;
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = 's';
            app.WindowtopeakEditFieldLabel = uilabel(app.UIFigure);
            app.WindowtopeakEditFieldLabel.Text = 'Window to peak';
            app.WindowtopeakEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindowtopeakEditField.Value = 10;
            app.msLabel_5 = uilabel(app.UIFigure);
            app.msLabel_5.Text = 'ms';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EPSP_launcher_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end