classdef SP_MINI_launcher_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AddtoexistingfileCheckBox     matlab.ui.control.CheckBox
        Lamp                          matlab.ui.control.Lamp
        DETAILSOFACQUISITIONLabel     matlab.ui.control.Label
        sLabel                        matlab.ui.control.Label
        DurationEditField             matlab.ui.control.NumericEditField
        DurationEditFieldLabel        matlab.ui.control.Label
        HzLabel                       matlab.ui.control.Label
        SamplingrateEditField         matlab.ui.control.NumericEditField
        SamplingrateEditFieldLabel    matlab.ui.control.Label
        CheckcellsCheckBox            matlab.ui.control.CheckBox
        DrawtracesLabel               matlab.ui.control.Label
        MinispontaneousactivityLabel  matlab.ui.control.Label
        RunMinianalysisButton         matlab.ui.control.Button
        DateTextArea                  matlab.ui.control.TextArea
        DateTextAreaLabel             matlab.ui.control.Label
        ExperimenterDropDown          matlab.ui.control.DropDown
        ExperimenterDropDownLabel     matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunMinianalysisButton
        function RunMinianalysisButtonPushed(app, event)

            app.Lamp.Color = 'red';
            pause(1)
            done = 0;
            if ~done
                done = run_Mini_SP_EPSC_analysis(app.ExperimenterDropDown.Value, app.DateTextArea.Value,...
                    app.CheckcellsCheckBox.Value,...
                    app.SamplingrateEditField.Value, app.DurationEditField.Value, ...
                    app.AddtoexistingfileCheckBox.Value);
                if done
                    app.Lamp.Color = 'green';
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Samples')
            ylabel(app.UIAxes, 'Current (pA)')
            zlabel(app.UIAxes, 'Z')
            app.ExperimenterDropDownLabel = uilabel(app.UIFigure);
            app.ExperimenterDropDownLabel.Text = 'Experimenter';
            app.ExperimenterDropDown = uidropdown(app.UIFigure);
            app.ExperimenterDropDown.Items = {'Federica', 'Fede_setup_1_5HT7', 'Fede_setup_1', 'Liselot', 'Liselot_setup_1', 'Niels', 'Kristina', 'Sri', 'Falk_et_at', 'Franziska'};
            app.ExperimenterDropDown.Value = 'Falk_et_at';
            app.DateTextAreaLabel = uilabel(app.UIFigure);
            app.DateTextAreaLabel.Text = 'Date';
            app.DateTextArea = uitextarea(app.UIFigure);
            app.DateTextArea.Value = {'220613'};
            app.RunMinianalysisButton = uibutton(app.UIFigure, 'push');
            app.RunMinianalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunMinianalysisButtonPushed, true);
            app.RunMinianalysisButton.Text = 'Run Mini analysis';
            app.MinispontaneousactivityLabel = uilabel(app.UIFigure);
            app.MinispontaneousactivityLabel.Text = 'Mini spontaneous activity';
            app.DrawtracesLabel = uilabel(app.UIFigure);
            app.DrawtracesLabel.Text = 'Draw traces';
            app.CheckcellsCheckBox = uicheckbox(app.UIFigure);
            app.CheckcellsCheckBox.Text = 'Check cells';
            app.CheckcellsCheckBox.Value = true;
            app.SamplingrateEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingrateEditFieldLabel.Text = 'Sampling rate';
            app.SamplingrateEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingrateEditField.Value = 8000;
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.DurationEditFieldLabel = uilabel(app.UIFigure);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationEditField.Value = 300;
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = 's';
            app.DETAILSOFACQUISITIONLabel = uilabel(app.UIFigure);
            app.DETAILSOFACQUISITIONLabel.Text = 'DETAILS OF ACQUISITION';
            app.Lamp = uilamp(app.UIFigure);
            app.AddtoexistingfileCheckBox = uicheckbox(app.UIFigure);
            app.AddtoexistingfileCheckBox.Text = 'Add to existing file';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SP_MINI_launcher_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end