classdef main_launcher_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MiniSPEPCsButton               matlab.ui.control.Button
        EPSPSummationButton            matlab.ui.control.Button
        SAGButton                      matlab.ui.control.Button
        PairingProtocolAnalysisButton  matlab.ui.control.Button
        PairedpulseRatioButton         matlab.ui.control.Button
        EPSCsButton                    matlab.ui.control.Button
        EPSPsButton                    matlab.ui.control.Button
        HavefunLabel                   matlab.ui.control.Label
        InvitroEphysAnalyzerLabel      matlab.ui.control.Label
        WelcometotheinvitroephysanalysisAPPLabel  matlab.ui.control.Label
        ActionPotentialButton          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ActionPotentialButton
        function ActionPotentialButtonPushed(app, event)
            close all
            AP_launcher_app()
        end

        % Button pushed function: EPSPsButton
        function EPSPsButtonPushed(app, event)
            close all
            EPSP_launcher_app()
        end

        % Button pushed function: EPSCsButton
        function EPSCsButtonPushed(app, event)
            close all
            EPSC_launcher_app()
        end

        % Button pushed function: PairedpulseRatioButton
        function PairedpulseRatioButtonPushed(app, event)
            close all
            PPR_launcher_app()
        end

        % Button pushed function: PairingProtocolAnalysisButton
        function PairingProtocolAnalysisButtonPushed(app, event)
            close all
            Pairing_launcher_app()
        end

        % Button pushed function: SAGButton
        function SAGButtonPushed(app, event)
            close all
            SAG_launcher_app()
        end

        % Button pushed function: EPSPSummationButton
        function EPSPSummationButtonPushed(app, event)
            close all
            EPSP_summation_launcher_app()
        end

        % Button pushed function: MiniSPEPCsButton
        function MiniSPEPCsButtonPushed(app, event)
            close all
            SP_MINI_launcher_app()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ActionPotentialButton = uibutton(app.UIFigure, 'push');
            app.ActionPotentialButton.ButtonPushedFcn = createCallbackFcn(app, @ActionPotentialButtonPushed, true);
            app.ActionPotentialButton.Text = 'Action Potential';
            app.WelcometotheinvitroephysanalysisAPPLabel = uilabel(app.UIFigure);
            app.WelcometotheinvitroephysanalysisAPPLabel.Text = 'Welcome to the in-vitro ephys analysis APP';
            app.InvitroEphysAnalyzerLabel = uilabel(app.UIFigure);
            app.InvitroEphysAnalyzerLabel.Text = 'In-vitro Ephys Analyzer';
            app.HavefunLabel = uilabel(app.UIFigure);
            app.HavefunLabel.Text = 'Have fun!';
            app.EPSPsButton = uibutton(app.UIFigure, 'push');
            app.EPSPsButton.ButtonPushedFcn = createCallbackFcn(app, @EPSPsButtonPushed, true);
            app.EPSPsButton.Text = 'EPSPs';
            app.EPSCsButton = uibutton(app.UIFigure, 'push');
            app.EPSCsButton.ButtonPushedFcn = createCallbackFcn(app, @EPSCsButtonPushed, true);
            app.EPSCsButton.Text = 'EPSCs';
            app.PairedpulseRatioButton = uibutton(app.UIFigure, 'push');
            app.PairedpulseRatioButton.ButtonPushedFcn = createCallbackFcn(app, @PairedpulseRatioButtonPushed, true);
            app.PairedpulseRatioButton.Text = 'Paired-pulse Ratio';
            app.PairingProtocolAnalysisButton = uibutton(app.UIFigure, 'push');
            app.PairingProtocolAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @PairingProtocolAnalysisButtonPushed, true);
            app.PairingProtocolAnalysisButton.Text = 'Pairing Protocol Analysis';
            app.SAGButton = uibutton(app.UIFigure, 'push');
            app.SAGButton.ButtonPushedFcn = createCallbackFcn(app, @SAGButtonPushed, true);
            app.SAGButton.Text = 'SAG';
            app.EPSPSummationButton = uibutton(app.UIFigure, 'push');
            app.EPSPSummationButton.ButtonPushedFcn = createCallbackFcn(app, @EPSPSummationButtonPushed, true);
            app.EPSPSummationButton.Text = 'EPSPSummation';
            app.MiniSPEPCsButton = uibutton(app.UIFigure, 'push');
            app.MiniSPEPCsButton.ButtonPushedFcn = createCallbackFcn(app, @MiniSPEPCsButtonPushed, true);
            app.MiniSPEPCsButton.Text = 'Mini SP EPCs';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_launcher_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end